classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        PlotButton            matlab.ui.control.Button
        Button_pointTest      matlab.ui.control.Button
        ReButton              matlab.ui.control.Button
        Button_roundGenerate  matlab.ui.control.Button
        CZTButton             matlab.ui.control.Button
        Button_noise          matlab.ui.control.Button
        GoertzelButton        matlab.ui.control.Button
        DFTButton             matlab.ui.control.Button
        FFTButton             matlab.ui.control.Button
        Button_filter         matlab.ui.control.Button
        TextArea              matlab.ui.control.TextArea
        Label                 matlab.ui.control.Label
        Button_save           matlab.ui.control.Button
        Button_draw           matlab.ui.control.Button
        Button_Generate       matlab.ui.control.Button
        Button_10             matlab.ui.control.Button
        Button_9              matlab.ui.control.Button
        Button_8              matlab.ui.control.Button
        Button_7              matlab.ui.control.Button
        Button_6              matlab.ui.control.Button
        Button_5              matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Button                matlab.ui.control.Button
        RightPanel            matlab.ui.container.Panel
        Panel                 matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        TextArea_2            matlab.ui.control.TextArea
        Label_2               matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
    TargetDigits = [];  % 存储选择的数字序列
    DTMFSignal = [];    % 存储生成的DTMF信号
    Fs = 8000;          % 采样频率8kHz
    EndpointSegments = [];
   decoded_digits = [];
    key_mag = [];
    f_axis = [];
    currentMethod = '';   % 'FFT' | 'DFT' | 'Goertzel' | 'CZT'
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function Button1Pushed2(app, event)
             app.TargetDigits = [app.TargetDigits,1];   % 点击1，加入序列
    app.TextArea.Value = ['',num2str(app.TargetDigits)]; % 文本框显示   
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.TargetDigits = [app.TargetDigits,2];   % 点击1，加入序列
    app.TextArea.Value = ['',num2str(app.TargetDigits)]; % 文本框显示
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.TargetDigits = [app.TargetDigits,3];   % 点击1，加入序列
    app.TextArea.Value = ['',num2str(app.TargetDigits)]; % 文本框显示
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.TargetDigits = [app.TargetDigits,4];   % 点击1，加入序列
    app.TextArea.Value = ['',num2str(app.TargetDigits)]; % 文本框显示
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.TargetDigits = [app.TargetDigits,5];   % 点击1，加入序列
    app.TextArea.Value = ['',num2str(app.TargetDigits)]; % 文本框显示
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.TargetDigits = [app.TargetDigits,6];   % 点击1，加入序列
    app.TextArea.Value = ['',num2str(app.TargetDigits)]; % 文本框显示
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.TargetDigits = [app.TargetDigits,7];   % 点击1，加入序列
    app.TextArea.Value = ['',num2str(app.TargetDigits)]; % 文本框显示
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.TargetDigits = [app.TargetDigits,8];   % 点击1，加入序列
    app.TextArea.Value = ['',num2str(app.TargetDigits)]; % 文本框显示
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.TargetDigits = [app.TargetDigits,9];   % 点击1，加入序列
    app.TextArea.Value = ['',num2str(app.TargetDigits)]; % 文本框显示
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.TargetDigits = [app.TargetDigits,0];   % 点击1，加入序列
    app.TextArea.Value = ['',num2str(app.TargetDigits)]; % 文本框显示
        end

        % Button pushed function: Button_Generate
        function Button_GeneratePushed(app, event)
    % Button pushed function: Btn_Generate

    % 1.参数设置 
    t_signal = 0.1;     % 信号持续100ms
    t_gap = 0.1;        % 间隔100ms
    %N_signal = app.Fs * t_signal;
    N_gap = app.Fs * t_gap;
    
    % 2.DTMF频率映射表 完全和你的代码一致
    dtmf_freq = [
        941, 1336;   % 0
        697, 1209;   % 1
        697, 1336;   % 2
        697, 1477;   % 3
        770, 1209;   % 4
        770, 1336;   % 5
        770, 1477;   % 6
        852, 1209;   % 7
        852, 1336;   % 8
        852, 1477    % 9
    ];
    
    % 3.获取选择的数字序列
    target_digits = app.TargetDigits;
    N_digits = length(target_digits);
    
    % 4.生成DTMF信号 完全和你的代码一致
    %dtmf_signal = [];
    t = 0:1/app.Fs:t_signal - 1/app.Fs;

    total_len = 0;
for i = 1:N_digits
    total_len = total_len + length(t) + N_gap;
end

% ===== 2. 预分配 =====
dtmf_signal = zeros(1, total_len);

% ===== 3. 写入指针 =====
idx = 1;

    for i = 1:N_digits
            digit = target_digits(i);
    f1 = dtmf_freq(digit + 1, 1);
    f2 = dtmf_freq(digit + 1, 2);

    signal = sin(2*pi*f1*t) + sin(2*pi*f2*t);
    gap = zeros(1, N_gap);

    L = length(signal) + length(gap);
    dtmf_signal(idx:idx+L-1) = [signal gap];
    idx = idx + L;
    end
    
    % 把生成的信号存入全局变量
    app.DTMFSignal = dtmf_signal;
    % 弹窗提示生成完成
    uialert(app.UIFigure,'DTMF信号生成成功！点击绘制波形查看','生成完成');

        end

        % Button pushed function: Button_draw
        function Button_drawPushed(app, event)

    % 获取生成的信号
    dtmf_signal = app.DTMFSignal;
    % 绘制时域波形 完全和你的代码一致
    t_total = 0:1/app.Fs:length(dtmf_signal)/app.Fs - 1/app.Fs;%给 dtmf_signal 每一个采样点，生成一个对应的时间坐标。
    cla(app.UIAxes,'reset');
    plot(app.UIAxes, t_total, dtmf_signal);  % 在APP的坐标图里绘图
    xlabel(app.UIAxes, '时间（s）');
    ylabel(app.UIAxes, '幅值');
    title(app.UIAxes, 'DTMF信号时域波形');
    grid(app.UIAxes, 'on');
    set(app.UIAxes,'FontSize',10);

        end

        % Button pushed function: Button_save
        function Button_savePushed(app, event)
    audiowrite('DTMF_signal.wav', app.DTMFSignal, app.Fs);
    uialert(app.UIFigure,'音频保存成功！文件名为DTMF_signal.wav','保存完成');
    fprintf('DTMF信号生成完成，已保存为DTMF_signal.wav\n');
        end

        % Button pushed function: Button_noise
        function Button_noisePushed(app, event)
if isempty(app.DTMFSignal)
    uialert(app.UIFigure,'请先生成DTMF信号','错误');
    return;
end

% ====== 指定信噪比 ======
SNRdB = 0;   

% 原始信号
x = app.DTMFSignal;

% 信号功率
Ps = mean(x.^2);%求平方和的平均值（平均功率）

% 噪声功率
Pn = Ps / (10^(SNRdB/10));

% 生成高斯白噪声
noise = sqrt(Pn) * randn(size(x));%randn 生成功率是 1标准高斯白噪声

% 加噪
y = x + noise;
y = y / max(abs(y));

% 保存到APP变量
app.DTMFSignal = y;
app.DTMFSignal = app.DTMFSignal(:)';
% ====== 保存为音频文件 ======
filename = ['DTMF_Noisy_SNR_',num2str(SNRdB),'dB.wav'];
audiowrite(filename, y, app.Fs);

% 绘制加噪信号
t = 0:1/app.Fs:length(y)/app.Fs-1/app.Fs;
cla(app.UIAxes,'reset');
plot(app.UIAxes,t,y);
title(app.UIAxes,['加噪DTMF信号 SNR=',num2str(SNRdB),' dB']);
xlabel(app.UIAxes,'时间(s)');
ylabel(app.UIAxes,'幅值');
grid(app.UIAxes,'on');

uialert(app.UIFigure,...
    ['已成功加噪并保存为 ',filename],...
    '完成');

        end

        % Button pushed function: Button_filter
        function Button_filterPushed(app, event)

x = app.DTMFSignal; 
 % ===== 设计带通滤波器 ===== 
N = 40; % 阶次 <=50 
Wn = [650 1600]/(app.Fs/2);%所有频率必须归一化到 Nyquist 频率 = Fs/2 
b = fir1(N,Wn,'bandpass');
 % 滤波 
y = filter(b,1,x);
app.DTMFSignal = y;
app.DTMFSignal = app.DTMFSignal(:)';
% 绘图 
t = 0:1/app.Fs:length(y)/app.Fs-1/app.Fs;
cla(app.UIAxes,'reset');
plot(app.UIAxes,t,y); 
title(app.UIAxes,'带通滤波后DTMF信号'); 
xlabel(app.UIAxes,'时间(s)'); 
ylabel(app.UIAxes,'幅值'); 
grid(app.UIAxes,'on'); 

% ====== 保存为音频文件 ======
filename = 'DTMF_Noisy_SNR_filter.wav';
audiowrite(filename, y, app.Fs);

uialert(app.UIFigure,...
    ['已成功去噪并保存为 ',filename],...
    '完成');


        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)


  segments = app.EndpointSegments;
x = app.DTMFSignal;
app.key_mag = [];
app.f_axis  = [];
app.decoded_digits = [];

row_freq = [697 770 852 941];
col_freq = [1209 1336 1477 1633];

digit_map = [1 2 3 NaN;
             4 5 6 NaN;
             7 8 9 NaN;
             NaN 0 NaN NaN];


for i = 1:size(segments,1)

    seg = x(segments(i,1):segments(i,2));

    N = 1024;
    Y = abs(fft(seg,N));
    f = (0:N-1)*app.Fs/N;

    Y = Y(1:N/2);
    f = f(1:N/2);
    if isempty(app.key_mag)
    app.key_mag = zeros(length(Y), size(segments,1));
    app.f_axis  = f(:);
    end
    app.key_mag(:,i) = Y(:);

    % 行频检测
    row_mags = zeros(1,4);
    for r=1:4
        [~,idx]=min(abs(f-row_freq(r)));
        row_mags(r)=Y(idx);
    end

    % 列频检测
    col_mags = zeros(1,4);
    for c=1:4
        [~,idx]=min(abs(f-col_freq(c)));
        col_mags(c)=Y(idx);
    end

    [~,ridx]=max(row_mags);
    [~,cidx]=max(col_mags);

    digit = digit_map(ridx,cidx);

    if ~isnan(digit)
        app.decoded_digits = [app.decoded_digits digit];
    end
end
%% ===== 绘制平均 FFT 频谱 =====
mean_mag = mean(app.key_mag, 2);

cla(app.UIAxes);
plot(app.UIAxes, app.f_axis, mean_mag, 'LineWidth', 1.5);
xlim(app.UIAxes, [500 1800]);
xlabel(app.UIAxes, 'Frequency (Hz)');
ylabel(app.UIAxes, 'Magnitude');
title(app.UIAxes, 'DTMF FFT Spectrum');
grid(app.UIAxes, 'on');

app.TextArea_2.Value = ['FFT解码结果: ', num2str(app.decoded_digits)];
app.currentMethod = 'FFT';


        end

        % Button pushed function: DFTButton
        function DFTButtonPushed(app, event)
          
if isempty(app.DTMFSignal)
    uialert(app.UIFigure,'请先生成DTMF信号','错误');
    return;
end

if isempty(app.EndpointSegments)
    uialert(app.UIFigure,'请先进行端点检测','错误');
    return;
end

dtmf_signal = app.DTMFSignal(:)';
app.Fs = app.Fs;

segments = app.EndpointSegments;

target_freqs = [697 770 852 941 1209 1336 1477 1633];
row_freq = target_freqs(1:4);
col_freq = target_freqs(5:8);

digit_map = [1 2 3 NaN;
             4 5 6 NaN;
             7 8 9 NaN;
             NaN 0 NaN NaN];

app.decoded_digits = [];
app.key_mag = [];
app.f_axis  = [697 770 852 941 1209 1336 1477 1633]';


%% ===== DFT 解码 =====
for i = 1:size(segments,1)

    frame = dtmf_signal(segments(i,1):segments(i,2));
    frame = frame(:)';

    N = length(frame);
    n = 0:N-1;

    mag_dft = zeros(1,8);

    for k = 1:8
        f = target_freqs(k);
        dft_val = sum(frame .* exp(-1j*2*pi*f*n/app.Fs));
        mag_dft(k) = abs(dft_val);
    end

    if max(mag_dft) < 15
        continue;
    end

    [~, idx] = sort(mag_dft,'descend');

    f_detected = sort(target_freqs(idx(1:2)));

    f_row = f_detected(1);
    f_col = f_detected(2);

    row_idx = find(abs(row_freq - f_row) < 15);
    col_idx = find(abs(col_freq - f_col) < 15);

    if ~isempty(row_idx) && ~isempty(col_idx)
        curr_digit = digit_map(row_idx,col_idx);

        if ~isnan(curr_digit)
            app.decoded_digits(end+1) = curr_digit;
        end
    end
    app.key_mag(:,end+1) = mag_dft(:);
end
app.currentMethod  = 'DFT';
%% ===== 显示解码结果 =====
app.TextArea_2.Value = ['DFT解码结果: ', num2str(app.decoded_digits)];

%% ===== 画整体DFT频谱 =====
all_mag = zeros(size(segments,1),8);

for i = 1:size(segments,1)
    frame = dtmf_signal(segments(i,1):segments(i,2));
    N = length(frame);
    n = 0:N-1;
    for k=1:8
        f = target_freqs(k);
        all_mag(i,k) = abs(sum(frame .* exp(-1j*2*pi*f*n/app.Fs)));
    end
end

mean_mag = mean(all_mag,1);

cla(app.UIAxes);
stem(app.UIAxes,target_freqs,mean_mag,'filled','LineWidth',1.5);
xlabel(app.UIAxes,'Frequency (Hz)');
ylabel(app.UIAxes,'Magnitude');
title(app.UIAxes,'DTMF DFT Spectrum');
grid(app.UIAxes,'on');


        end

        % Button pushed function: GoertzelButton
        function GoertzelButtonPushed(app, event)


   % Button pushed function: GoertzelButton

if isempty(app.DTMFSignal)
    uialert(app.UIFigure,'请先生成DTMF信号','错误');
    return;
end

if isempty(app.EndpointSegments)
    uialert(app.UIFigure,'请先进行端点检测','错误');
    return;
end

x = app.DTMFSignal;
app.Fs = app.Fs;

row_freq = [697, 770, 852, 941];
col_freq = [1209, 1336, 1477, 1633];
target_freqs = [row_freq col_freq];

digit_map = [1 2 3 NaN;
             4 5 6 NaN;
             7 8 9 NaN;
             NaN 0 NaN NaN];

segments = app.EndpointSegments;


app.key_mag = [];
app.f_axis  = [697 770 852 941 1209 1336 1477 1633]';
app.decoded_digits = [];
%% ===== Goertzel函数 =====
function mag = goertzel_real(x, Fs, f)
    N = length(x);
    w = 2*pi*f/Fs;
    coeff = 2*cos(w);
    s1 = 0; s2 = 0;

    for n = 1:N
        s = x(n) + coeff*s1 - s2;
        s2 = s1;
        s1 = s;
    end

    mag = sqrt(s1^2 + s2^2 - coeff*s1*s2);
end

%% ===== 解码每个端点段 =====
for i = 1:size(segments,1)

    frame = x(segments(i,1):segments(i,2));

    mags = zeros(1,8);

    for k = 1:8
        mags(k) = goertzel_real(frame, app.Fs, target_freqs(k));
    end

    if max(mags) < 20
        continue;
    end

    [~, idx] = sort(mags,'descend');

    f_low  = min(target_freqs(idx(1:2)));
    f_high = max(target_freqs(idx(1:2)));

    row_idx = find(abs(row_freq - f_low) < 20);
    col_idx = find(abs(col_freq - f_high) < 20);

    if ~isempty(row_idx) && ~isempty(col_idx)

        curr_digit = digit_map(row_idx, col_idx);

        if ~isnan(curr_digit)
            app.decoded_digits(end+1) = curr_digit;
        end
    end
    app.key_mag(:,end+1) = mags(:);

end

%% ===== 显示结果 =====
app.TextArea_2.Value = ['Goertzel解码结果: ', num2str(app.decoded_digits)];
app.currentMethod  = 'Goertzel';


        end

        % Button pushed function: CZTButton
        function CZTButtonPushed(app, event)
           
 % Button pushed function: CZTButton

if isempty(app.DTMFSignal)
    uialert(app.UIFigure, '请先生成DTMF信号', '错误');
    return;
end

if isempty(app.EndpointSegments)
    uialert(app.UIFigure, '请先进行端点检测', '错误');
    return;
end

% 获取信号
x = app.DTMFSignal(:)';
app.Fs = app.Fs;

segments = app.EndpointSegments;

% DTMF参数
row_freq = [697 770 852 941];
col_freq = [1209 1336 1477 1633];

digit_map = [1 2 3 NaN;
             4 5 6 NaN;
             7 8 9 NaN;
             NaN 0 NaN NaN];

% CZT参数
f1 = 650;
f2 = 1600;
N_czt = 1024;
freq_axis = linspace(f1,f2,N_czt);

app.decoded_digits = [];
all_mag = zeros(size(segments,1),N_czt);
app.key_mag = [];
% ===== 主循环：按端点段处理 =====
for i = 1:size(segments,1)

    frame = x(segments(i,1):segments(i,2));

    if sum(frame.^2) < 1e-6
        continue;
    end

    % CZT计算
    czt_spec = abs(czt(frame, N_czt, ...
        exp(-1j*2*pi*(f2-f1)/((N_czt-1)*app.Fs)), ...
        exp(1j*2*pi*f1/app.Fs)));

    all_mag(i,:) = czt_spec;

    row_mag = zeros(1,4);
    col_mag = zeros(1,4);

    % 取行频幅值
    for k=1:4
        [~,idx]=min(abs(freq_axis-row_freq(k)));
        row_mag(k)=czt_spec(idx);
    end

    % 取列频幅值
    for k=1:4
        [~,idx]=min(abs(freq_axis-col_freq(k)));
        col_mag(k)=czt_spec(idx);
    end

    % 找最大行列频
    [~,r_idx]=max(row_mag);
    [~,c_idx]=max(col_mag);

    % 阈值判断
    if row_mag(r_idx)>0.15*max(czt_spec) && col_mag(c_idx)>0.15*max(czt_spec)

        digit = digit_map(r_idx,c_idx);

        if ~isnan(digit)
            app.decoded_digits=[app.decoded_digits digit];
        end
    end
    app.key_mag(:,end+1) = czt_spec(:);
app.f_axis = freq_axis(:);
end

% ===== 显示结果 =====
app.TextArea_2.Value = ['CZT解码结果: ', num2str(app.decoded_digits)];

% ===== 绘制平均CZT频谱 =====
mean_mag = mean(all_mag,1);

cla(app.UIAxes);
plot(app.UIAxes,freq_axis,mean_mag,'LineWidth',1.5);
xlabel(app.UIAxes,'Frequency (Hz)');
ylabel(app.UIAxes,'Magnitude');
title(app.UIAxes,'DTMF CZT Spectrum');
grid(app.UIAxes,'on');

app.currentMethod  = 'CZT';

        end

        % Button pushed function: Button_roundGenerate
        function Button_roundGeneratePushed(app, event)


% ===== 1. 随机11位号码 =====
app.TargetDigits = randi([0 9],1,11);
app.TextArea.Value = num2str(app.TargetDigits);

% ===== 2. DTMF频率表 =====
dtmf_freq = [
    941, 1336;
    697, 1209;
    697, 1336;
    697, 1477;
    770, 1209;
    770, 1336;
    770, 1477;
    852, 1209;
    852, 1336;
    852, 1477
];


% ===== 3. 逐个生成（预计算总长度） =====
total_len = 0;

t_signal_list = zeros(1,length(app.TargetDigits));
t_gap_list    = zeros(1,length(app.TargetDigits));

for i = 1:length(app.TargetDigits)

    % 随机时长先存起来（非常关键）
    t_signal_list(i) = 0.05 + (0.1-0.05)*rand;
    t_gap_list(i)    = 0.1  + (0.2-0.1)*rand;

    total_len = total_len + ...
        round(t_signal_list(i)*app.Fs) + ...
        round(t_gap_list(i)*app.Fs);
end
dtmf_signal = zeros(1, total_len);
idx = 1;

for i = 1:length(app.TargetDigits)

    digit = app.TargetDigits(i);
    f1 = dtmf_freq(digit+1,1);
    f2 = dtmf_freq(digit+1,2);

    % ===== 信号段 =====
    Ns = round(t_signal_list(i)*app.Fs);
    t  = (0:Ns-1)/app.Fs;
    tone = sin(2*pi*f1*t) + sin(2*pi*f2*t);

    % ===== 间隔段 =====
    Ng = round(t_gap_list(i)*app.Fs);
    gap = zeros(1,Ng);

    L = Ns + Ng;
    dtmf_signal(idx:idx+L-1) = [tone gap];
    idx = idx + L;
end

% ===== 4. 归一化 =====
dtmf_signal = dtmf_signal / max(abs(dtmf_signal));

% ===== 5. 随机加噪 =====
SNRdB = randi([-5 25]);
Ps = mean(dtmf_signal.^2);
Pn = Ps/(10^(SNRdB/10));
noise = sqrt(Pn)*randn(size(dtmf_signal));

noisy_signal = dtmf_signal + noise;
noisy_signal = noisy_signal / max(abs(noisy_signal));

% ===== 6. 保存 =====
app.DTMFSignal = noisy_signal;

uialert(app.UIFigure,...
    ['已生成随机DTMF信号，SNR=',num2str(SNRdB),' dB'],...
    '完成');


        end

        % Button pushed function: ReButton
        function ReButtonPushed(app, event)
         % ===== 清空数据 =====
app.TargetDigits = [];
app.DTMFSignal = [];
app.EndpointSegments = [];
app.decoded_digits = [];
app.key_mag = [];
app.f_axis = [];
app.currentMethod = '';

% ===== 清空文本 =====
app.TextArea.Value = '';
app.TextArea_2.Value = '';

% ===== 清空主坐标轴 =====
cla(app.UIAxes,'reset');

% ===== 清空面板内所有子图 =====
delete(app.GridLayout2.Children);

% （可选）恢复默认布局
app.GridLayout2.RowHeight = {'1x'};
app.GridLayout2.ColumnWidth = {'1x'};


        end

        % Button pushed function: Button_pointTest
        function Button_pointTestPushed(app, event)


x = app.DTMFSignal;
app.Fs = app.Fs;

frame_len  = round(0.02*app.Fs);   % 20ms
frame_step = round(0.01*app.Fs);   % 10ms

N = floor((length(x)-frame_len)/frame_step)+1;

energy = zeros(1,N);
zcr = zeros(1,N);

for i = 1:N
    frame = x((i-1)*frame_step+1:(i-1)*frame_step+frame_len);
    energy(i) = sum(frame.^2);
    zcr(i) = sum(abs(diff(sign(frame))))/2;
end

energy = energy / max(energy);
%zcr = zcr / max(zcr);

energy_smooth = movmean(energy,5);

%% ===== 自适应阈值 =====
Th_high = mean(energy_smooth) + 0.6*std(energy_smooth);
Th_low  = mean(energy_smooth) + 0.3*std(energy_smooth);


segments = zeros(N,2);   % 最多 N 个段
seg_cnt = 0;
flag = 0;
for i = 1:N
    if flag==0 && energy_smooth(i) > Th_high
        start_frame = i;
        flag = 1;

    elseif flag==1 && energy_smooth(i) < Th_low
        end_frame = i;
        flag = 0;

        start_sample = (start_frame-1)*frame_step + 1;
        end_sample   = (end_frame-1)*frame_step + frame_len;

        seg_cnt = seg_cnt + 1;
        segments(seg_cnt,:) = [start_sample end_sample];
    end
end
segments = segments(1:seg_cnt,:);

%% ===== 删除过短段 =====
min_len = 0.03*app.Fs;
segments = segments(segments(:,2)-segments(:,1)>min_len,:);

%% ===== 合并过近段 =====
merged = zeros(size(segments));   % 预分配
m_cnt = 0;

if ~isempty(segments)
    curr = segments(1,:);
    for i = 2:size(segments,1)
        if segments(i,1) - curr(2) < 0.03*app.Fs
            curr(2) = segments(i,2);
        else
            m_cnt = m_cnt + 1;
            merged(m_cnt,:) = curr;
            curr = segments(i,:);
        end
    end
    m_cnt = m_cnt + 1;
    merged(m_cnt,:) = curr;
end

segments = merged(1:m_cnt,:);
app.EndpointSegments = segments;

% ===== 绘图 =====
t = (0:length(x)-1)/app.Fs;

cla(app.UIAxes);
plot(app.UIAxes,t,x,'b'); hold(app.UIAxes,'on');

for i=1:size(segments,1)
    xline(app.UIAxes,segments(i,1)/app.Fs,'r','LineWidth',1.5);
    xline(app.UIAxes,segments(i,2)/app.Fs,'g','LineWidth',1.5);
end

hold(app.UIAxes,'off');
grid(app.UIAxes,'on');
title(app.UIAxes,'DTMF Endpoint Detection');
xlabel(app.UIAxes,'Time (s)');
ylabel(app.UIAxes,'Amplitude');

nSeg = size(segments,1);
lines = strings(nSeg,1);

for i = 1:nSeg
    lines(i) = sprintf('%02d: %.3f → %.3f s', ...
        i, segments(i,1)/app.Fs, segments(i,2)/app.Fs);
end

msg = "检测到的按键段：" + newline + join(lines, newline);
app.TextArea_2.Value = msg;
app.TextArea_2.Value = msg;

uialert(app.UIFigure,'端点检测完成','完成');


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
           N_plot = length(app.decoded_digits);
if N_plot == 0
    uialert(app.UIFigure,'没有可绘制的频谱，请先解码','提示');
    return;
end

cols = 4;
rows = ceil(N_plot/cols);

% 清空旧图
delete(app.GridLayout2.Children)

% 设置布局
app.GridLayout2.RowHeight = repmat({'1x'},1,rows);
app.GridLayout2.ColumnWidth = repmat({'1x'},1,cols);

for i = 1:N_plot
    ax = uiaxes(app.GridLayout2);

    plot(ax, app.f_axis, app.key_mag(:,i),'k','LineWidth',1.2);
    grid(ax,'on');
    xlim(ax,[500 1800]);
    ylim(ax,[0 max(app.key_mag(:,i))*1.1]);

   title(ax, sprintf('%s  Digit: %d', ...
    app.currentMethod, app.decoded_digits(i)));
end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {566, 566};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {282, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed2, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.LeftPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.LeftPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.LeftPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.LeftPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.LeftPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.LeftPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.LeftPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.LeftPanel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '0';
            app.Button_Generate = uibutton(app.LeftPanel, 'push');
            app.Button_Generate.ButtonPushedFcn = createCallbackFcn(app, @Button_GeneratePushed, true);
            app.Button_Generate.Text = '生成信号';
            app.Button_draw = uibutton(app.LeftPanel, 'push');
            app.Button_draw.ButtonPushedFcn = createCallbackFcn(app, @Button_drawPushed, true);
            app.Button_draw.Text = '绘制波形';
            app.Button_save = uibutton(app.LeftPanel, 'push');
            app.Button_save.ButtonPushedFcn = createCallbackFcn(app, @Button_savePushed, true);
            app.Button_save.Text = '保存音频';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = ' ';
            app.TextArea = uitextarea(app.LeftPanel);
            app.Button_filter = uibutton(app.LeftPanel, 'push');
            app.Button_filter.ButtonPushedFcn = createCallbackFcn(app, @Button_filterPushed, true);
            app.Button_filter.Text = '去噪滤波';
            app.FFTButton = uibutton(app.LeftPanel, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Text = 'FFT';
            app.DFTButton = uibutton(app.LeftPanel, 'push');
            app.DFTButton.ButtonPushedFcn = createCallbackFcn(app, @DFTButtonPushed, true);
            app.DFTButton.Text = 'DFT';
            app.GoertzelButton = uibutton(app.LeftPanel, 'push');
            app.GoertzelButton.ButtonPushedFcn = createCallbackFcn(app, @GoertzelButtonPushed, true);
            app.GoertzelButton.Text = 'Goertzel';
            app.Button_noise = uibutton(app.LeftPanel, 'push');
            app.Button_noise.ButtonPushedFcn = createCallbackFcn(app, @Button_noisePushed, true);
            app.Button_noise.Text = '“噪起来”';
            app.CZTButton = uibutton(app.LeftPanel, 'push');
            app.CZTButton.ButtonPushedFcn = createCallbackFcn(app, @CZTButtonPushed, true);
            app.CZTButton.Text = 'CZT';
            app.Button_roundGenerate = uibutton(app.LeftPanel, 'push');
            app.Button_roundGenerate.ButtonPushedFcn = createCallbackFcn(app, @Button_roundGeneratePushed, true);
            app.Button_roundGenerate.Text = '随机信号生成';
            app.ReButton = uibutton(app.LeftPanel, 'push');
            app.ReButton.ButtonPushedFcn = createCallbackFcn(app, @ReButtonPushed, true);
            app.ReButton.Text = 'Re';
            app.Button_pointTest = uibutton(app.LeftPanel, 'push');
            app.Button_pointTest.ButtonPushedFcn = createCallbackFcn(app, @Button_pointTestPushed, true);
            app.Button_pointTest.Text = '端点检测';
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '解码结果';
            app.TextArea_2 = uitextarea(app.RightPanel);
            app.Panel = uipanel(app.RightPanel);
            app.Panel.Title = 'Panel';
            app.GridLayout2 = uigridlayout(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end